classdef ReverberantTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ResetMenu                       matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        RawsignalTab                    matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel4                          matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        Meanreverberationtimes30byBIsLabel  matlab.ui.control.Label
        UITableBIRTMeanResults          matlab.ui.control.Table
        Reverberationtimes30byBIsLabel  matlab.ui.control.Label
        ReverberationtimessLabel        matlab.ui.control.Label
        UITableBIRTResults              matlab.ui.control.Table
        UITableRTResults                matlab.ui.control.Table
        Panel2                          matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        BrojuzorakazausrednjavanjesirovogtlakaEditField  matlab.ui.control.NumericEditField
        BrojuzorakazausrednjavanjesirovogtlakaEditFieldLabel  matlab.ui.control.Label
        TimeforbackgroundnoisesEditField  matlab.ui.control.NumericEditField
        TimeforbackgroundnoisesEditFieldLabel  matlab.ui.control.Label
        BrojuzorakazausrednjavanjeRMSatlakaEditField  matlab.ui.control.NumericEditField
        BrojuzorakazausrednjavanjeRMSatlakaEditFieldLabel  matlab.ui.control.Label
        ProcessfileButton               matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        RawdatapathEditField            matlab.ui.control.EditField
        RawdatapathEditFieldLabel       matlab.ui.control.Label
        UIAxesDecayCurve                matlab.ui.control.UIAxes
        ReverberanttimeTab              matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ResultsPanel_6                  matlab.ui.container.Panel
        UITableRT                       matlab.ui.control.Table
        CursorsPanel                    matlab.ui.container.Panel
        EndSliderRT                     matlab.ui.control.Slider
        EndSliderLabel                  matlab.ui.control.Label
        StartSliderRT                   matlab.ui.control.Slider
        StartSliderLabel                matlab.ui.control.Label
        UIAxesRT                        matlab.ui.control.UIAxes
        UIAxesRTCut                     matlab.ui.control.UIAxes
        OctavefiltersTab                matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        HzTab_6                         matlab.ui.container.Tab
        GridLayout2_8                   matlab.ui.container.GridLayout
        ResultsPanel                    matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        ReverberationtimesBIsLabel      matlab.ui.control.Label
        ReverberationtimessslidersLabel  matlab.ui.control.Label
        UITable250Hz                    matlab.ui.control.Table
        UITableBI250Hz                  matlab.ui.control.Table
        CursorsPanel_8                  matlab.ui.container.Panel
        EndSlider250Hz                  matlab.ui.control.Slider
        EndSlider_2Label                matlab.ui.control.Label
        StartSlider250Hz                matlab.ui.control.Slider
        StartSlider_2Label              matlab.ui.control.Label
        UIAxesRT250Hz                   matlab.ui.control.UIAxes
        UIAxesRT250HzCut                matlab.ui.control.UIAxes
        UIAxesRTBI250Hz                 matlab.ui.control.UIAxes
        HzTab_7                         matlab.ui.container.Tab
        GridLayout2_9                   matlab.ui.container.GridLayout
        ResultsPanel_2                  matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        ReverberationtimessBILabel      matlab.ui.control.Label
        ReverberationtimessslidersLabel_2  matlab.ui.control.Label
        UITableBI500Hz                  matlab.ui.control.Table
        UITable500Hz                    matlab.ui.control.Table
        CursorsPanel_9                  matlab.ui.container.Panel
        EndSlider500Hz                  matlab.ui.control.Slider
        EndSlider_3Label                matlab.ui.control.Label
        StartSlider500Hz                matlab.ui.control.Slider
        StartSlider_3Label              matlab.ui.control.Label
        UIAxesRT500Hz                   matlab.ui.control.UIAxes
        UIAxesRT500HzCut                matlab.ui.control.UIAxes
        UIAxesRTBI500Hz                 matlab.ui.control.UIAxes
        HzTab_8                         matlab.ui.container.Tab
        GridLayout2_10                  matlab.ui.container.GridLayout
        ResultsPanel_3                  matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        ReverberationtimessBILabel_2    matlab.ui.control.Label
        ReverberationtimessslidersLabel_3  matlab.ui.control.Label
        UITableBI1000Hz                 matlab.ui.control.Table
        UITable1000Hz                   matlab.ui.control.Table
        CursorsPanel_10                 matlab.ui.container.Panel
        EndSlider1000Hz                 matlab.ui.control.Slider
        EndSlider_4Label                matlab.ui.control.Label
        StartSlider1000Hz               matlab.ui.control.Slider
        StartSlider_4Label              matlab.ui.control.Label
        UIAxesRT1000Hz                  matlab.ui.control.UIAxes
        UIAxesRT1000HzCut               matlab.ui.control.UIAxes
        UIAxesRTBI1000Hz                matlab.ui.control.UIAxes
        HzTab_9                         matlab.ui.container.Tab
        GridLayout2_11                  matlab.ui.container.GridLayout
        ResultsPanel_4                  matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        ReverberationtimessBILabel_3    matlab.ui.control.Label
        ReverberationtimessslidersLabel_4  matlab.ui.control.Label
        UITableBI2000Hz                 matlab.ui.control.Table
        UITable2000Hz                   matlab.ui.control.Table
        CursorsPanel_11                 matlab.ui.container.Panel
        EndSlider2000Hz                 matlab.ui.control.Slider
        EndSlider_6Label                matlab.ui.control.Label
        StartSlider2000Hz               matlab.ui.control.Slider
        StartSlider_6Label              matlab.ui.control.Label
        UIAxesRT2000Hz                  matlab.ui.control.UIAxes
        UIAxesRT2000HzCut               matlab.ui.control.UIAxes
        UIAxesRTBI2000Hz                matlab.ui.control.UIAxes
        HzTab_10                        matlab.ui.container.Tab
        GridLayout2_12                  matlab.ui.container.GridLayout
        ResultsPanel_5                  matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        ReverberationtimessBILabel_4    matlab.ui.control.Label
        ReverberationtimessslidersLabel_5  matlab.ui.control.Label
        UITableBI4000Hz                 matlab.ui.control.Table
        UITable4000Hz                   matlab.ui.control.Table
        CursorsPanel_12                 matlab.ui.container.Panel
        EndSlider4000Hz                 matlab.ui.control.Slider
        EndSlider_5Label                matlab.ui.control.Label
        StartSlider4000Hz               matlab.ui.control.Slider
        StartSlider_5Label              matlab.ui.control.Label
        UIAxesRT4000HzCut               matlab.ui.control.UIAxes
        UIAxesRT4000Hz                  matlab.ui.control.UIAxes
        UIAxesRTBI4000Hz                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        lvmFiles
        results
        fileData
        xLines
        xLinesBI
        slopeLines
        slopeLinesBI
        data
        lookupGraphObjs
        lookupTableObjs
        lookupTableBIObjs
        lookupIntLim
    end
    
    methods (Access = public)
        
        
        function DisplayUIAlert(app, Title, Message)
            uialert(app.UIFigure, Message, Title);
        end
        
        function PlotDecayCurves(app)
            % Function plots decay curves
            for ii = 1 : length(app.results)
                plot(app.UIAxesDecayCurve, app.results(ii).dt, app.results(ii).SPL)
                plot(app.UIAxesRT, app.results(ii).dt, app.results(ii).SPL)
                plot(app.UIAxesRT250Hz, app.results(ii).dt, app.results(ii).filteredSig(1).SPL)
                plot(app.UIAxesRT500Hz, app.results(ii).dt, app.results(ii).filteredSig(2).SPL)
                plot(app.UIAxesRT1000Hz, app.results(ii).dt, app.results(ii).filteredSig(3).SPL)
                plot(app.UIAxesRT2000Hz, app.results(ii).dt, app.results(ii).filteredSig(4).SPL)
                plot(app.UIAxesRT4000Hz, app.results(ii).dt, app.results(ii).filteredSig(5).SPL)
            end
        end
        
        function PlotDecayCurvesBI(app)
            % PlotDecayCurvesBI plots decay curves obtained by Schroeder BI, and vertical lines for the dynamic range T30 
            % Loop over filtered signals
            for ii = 1 : size(app.data.filter.bandWidth, 1)
                
                sigLength = size(app.results(1).filteredSig(ii).EDCBILogNorm, 1);
                plot(app.lookupGraphObjs(ii+1, 5), app.results(1).dt(1:sigLength), app.results(1).filteredSig(ii).EDCBILogNorm);% Plotting the normalised decay curve obtained by BI
                [app.results(1).filteredSig(ii).startIndex, app.results(1).filteredSig(ii).endIndex] = ...
                    CalculateDynRangeT30(app, app.results(1).filteredSig(ii).EDCBILogNorm); 

                startIndex = app.results(1).filteredSig(ii).startIndex; % Short names
                endIndex = app.results(1).filteredSig(ii).endIndex; % Short names
                app.xLinesBI(ii*2-1) = xline(app.lookupGraphObjs(ii+1, 5), app.results(1).dt(startIndex(1)), 'LineWidth', 1, 'Label', 'Start');
                app.xLinesBI(ii*2) = xline(app.lookupGraphObjs(ii+1, 5), app.results(1).dt(endIndex(1)), 'LineWidth', 1, 'Label', 'End');
                
                %% Fitting line with least squares method within the dynamic range
                % Initialisation/short names for variables
                BILogNorm = app.results(1).filteredSig(ii).EDCBILogNorm;
                dt = app.results(1).dt;
                fitCoeffs = zeros(size(BILogNorm, 2), 2);
                
                %% Looping over microphone signals
                % Getting coefficients for the fitted line
                for jj = 1 : size(BILogNorm, 2)
                   fitCoeffs(jj,:) = polyfit(dt(startIndex:endIndex, 1), ...
                       BILogNorm(startIndex:endIndex, jj), 1);
                end
                
                % Evaluates lines and plots them on graph
                for kk = 1 : size(BILogNorm, 2)
                    fitLine(:, kk) = polyval(fitCoeffs(kk,:), dt(startIndex:endIndex));
                end
                app.slopeLinesBI(ii,:) = plot(app.lookupGraphObjs(ii+1,5), dt(startIndex:endIndex), fitLine);
                
                % Reverberation time
                revTime = -60./fitCoeffs(:,1)';
                app.lookupTableBIObjs(ii).Data = revTime;
            
                clear fitLine fitCoeffs % Every filtered signal (ii loop) has different size, therefore clear is necessary

                % Renaming the column names in the table
                colString = cell(1, numel(revTime));
                for mm = 1 : numel(revTime)
                    colString{mm} = sprintf('Microphone %d', mm);
                end
                app.lookupTableBIObjs(ii).ColumnName = colString;
            end
            
            % Fill the table with the RTs obtained by BI
            resultsRevTime = reshape([app.lookupTableBIObjs.Data], numel(revTime), [])';
            app.UITableBIRTResults.Data = resultsRevTime;
            app.UITableBIRTResults.ColumnName = colString;
            app.UITableBIRTResults.RowName = { ...
                'RT30 250Hz [s]';
                'RT30 500Hz [s]';
                'RT30 1000Hz [s]'; 
                'RT30 2000Hz [s]';
                'RT30 4000Hz [s]'};
            app.UITableBIRTMeanResults.Data = mean(resultsRevTime, 2);
            app.UITableBIRTMeanResults.RowName = { ...
                'RT30 250Hz [s]';
                'RT30 500Hz [s]';
                'RT30 1000Hz [s]'; 
                'RT30 2000Hz [s]';
                'RT30 4000Hz [s]'};
            app.UITableBIRTMeanResults.ColumnName = 'Mean value [s]';
            
        end
        
% %         function PlotNormalisedDecayCurves(app)
% %             for ii = 1 : length(app.results)
% %                 plot(app.UIAxesDecayCurve, app.results(ii).dt, app.results(ii).SPL)
% %                 plot(app.UIAxesRT, app.results(ii).dt, app.results(ii).SPL)
% %                 plot(app.UIAxesRT250Hz, app.results(ii).dt, app.results(ii).filteredSig(1).SPL)
% %                 plot(app.UIAxesRT500Hz, app.results(ii).dt, app.results(ii).filteredSig(2).SPL)
% %                 plot(app.UIAxesRT1000Hz, app.results(ii).dt, app.results(ii).filteredSig(3).SPL)
% %                 plot(app.UIAxesRT2000Hz, app.results(ii).dt, app.results(ii).filteredSig(4).SPL)
% %                 plot(app.UIAxesRT4000Hz, app.results(ii).dt, app.results(ii).filteredSig(5).SPL)
% %             end
% %         end
        
        function CalculateRT(app, source)
            
            % Find which component in the lookup table has fired the event           
            [row, ~] = find(source == app.lookupGraphObjs);
            
            lookupSPL = {...
               app.results(1).SPL;
               app.results(1).filteredSig(1).SPL;
               app.results(1).filteredSig(2).SPL;
               app.results(1).filteredSig(3).SPL;
               app.results(1).filteredSig(4).SPL;
               app.results(1).filteredSig(5).SPL};
           
            % Inicijalizacija varijabli
            dt = app.results(1).dt;
            SPL = lookupSPL{row};
           
            % Odrediti indekse za pocetak i kraj
            startIndex = find(app.xLines(row*2-1).Value == dt);
            endIndex = find(app.xLines(row*2).Value == dt);
            
            % Save indices of the sliders 
            switch row
                case 1
                    app.results(1).intLim = endIndex;
                case 2
                    app.results(1).filteredSig(1).intLim = endIndex;
                case 3
                    app.results(1).filteredSig(2).intLim = endIndex;
                case 4
                    app.results(1).filteredSig(3).intLim = endIndex;
                case 5
                    app.results(1).filteredSig(4).intLim = endIndex;
                case 6
                    app.results(1).filteredSig(5).intLim = endIndex;
            end
            
            fitCoeffs = zeros(size(SPL, 2), 2);
            % Looping over signals
            for ii = 1 : size(SPL, 2)
               fitCoeffs(ii,:) = polyfit(dt(startIndex:endIndex, 1), ...
                   SPL(startIndex:endIndex, ii), 1);
            end
            
            % Evaluates lines and plots them on graph
            for ii = 1 : size(SPL, 2)
                fitLine(:, ii) = polyval(fitCoeffs(ii,:), dt(startIndex:endIndex,1));
            end
            app.slopeLines(row).XData = dt(startIndex:endIndex);
            app.slopeLines(row).YData = fitLine(:,1);
            plot(app.lookupGraphObjs(row, 4), dt(startIndex:endIndex), [fitLine, SPL(startIndex:endIndex, :)]);
            
            % Reverberation time
            revTime = -60./fitCoeffs(:,1)';
            app.lookupTableObjs(row).Data = revTime;
            
            % Saving the values in the table
            colString = cell(1, numel(revTime));
            for ii = 1 : numel(revTime)
                colString{ii} = sprintf('Microphone %d', ii);
            end
            app.lookupTableObjs(row).ColumnName = colString;
            
            % Fill the results, only when RTs for all graphs have been
            % calculated
            resultsrevTime = reshape([app.lookupTableObjs.Data], numel(revTime), [])';
            if size(resultsrevTime, 1) == 6
                app.UITableRTResults.Data = resultsrevTime;
                app.UITableRTResults.ColumnName = colString;
                app.UITableRTResults.RowName = { ...
                    'RT [s]';
                    'RT 250Hz [s]';
                    'RT 500Hz [s]';
                    'RT 1000Hz [s]'; 
                    'RT 2000Hz [s]';
                    'RT 4000Hz [s]'};
            end

        end
        
        function soundPressureRMS = CalculateSPRMS(app, rawSignal)
            % CalculateSPRMS calculates RMS value of sound pressure from raw signal
            numSampsForAvg = app.BrojuzorakazausrednjavanjeRMSatlakaEditField.Value; % Number of samples for averaging the RMS
            soundPressureRMS = sqrt(movmean(rawSignal.^2, [numSampsForAvg 0], 1));
        end
        
        function SPL = CalculateSPL(~, soundPressureRMS)
            % CalculateSPL calculates sound pressure level from RMS values
            % of sound pressure
            SPL = 20*log10(soundPressureRMS/2e-5);
        end
        
        function [intStartInd, intEndInd] = CalculateDynRangeT30(~, signal)
            % CalculateDynRangeT30 returns indices of the signal that
            % correspond to dynamic range 5-35 dB below the peak amplitude
            % signal is an array with units in dB
            signalMax = max(signal, [], 1);
            [~, intStartInd] = min(abs(signalMax - signal - 5), [], 1);
            [~, intEndInd] = min(abs(signalMax - signal - 35), [], 1);
        end
        
        function PlotLegends(app)
            legString = cell(1,size(app.results(1).pressureRaw, 2));
            for mm = 1 : numel(legString)
                legString{mm} = sprintf('Microphone %d', mm);
            end
            
            for ii = 1:size(app.lookupGraphObjs,1)
                if ~isempty(app.lookupGraphObjs(ii,3).Children)
                    legend(app.lookupGraphObjs(ii,3), legString); % Decay curves
                end
                if ~isempty(app.lookupGraphObjs(ii,4).Children)
                    legend(app.lookupGraphObjs(ii,4), legString); % Decay curve extracts
                end
            end
            
            for jj = 2:size(app.lookupGraphObjs,1)
                if ~isempty(app.lookupGraphObjs(jj,5).Children)
                    legend(app.lookupGraphObjs(jj,5), legString); % Energy time curve BI
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %% This code runs when the app building has been completed
        % Central frequencies and corresponding octave bands    
        app.data.filter.centralFreqs = [250, 500, 1000, 2000, 4000]';
        app.data.filter.lowerFreqs = [177, 355, 710, 1420, 2840]';
        app.data.filter.upperFreqs = [355, 710, 1420, 2840, 5680]';
        app.data.filter.bandWidth = app.data.filter.upperFreqs-app.data.filter.lowerFreqs;
        
        % Creating an array that contains handles to components for each graph
        app.lookupGraphObjs = gobjects(6,5);
        app.lookupGraphObjs = [...
                app.StartSliderRT, app.EndSliderRT, app.UIAxesRT, app.UIAxesRTCut, gobjects(1);
                app.StartSlider250Hz, app.EndSlider250Hz, app.UIAxesRT250Hz, app.UIAxesRT250HzCut, app.UIAxesRTBI250Hz;
                app.StartSlider500Hz, app.EndSlider500Hz, app.UIAxesRT500Hz, app.UIAxesRT500HzCut, app.UIAxesRTBI500Hz;
                app.StartSlider1000Hz, app.EndSlider1000Hz, app.UIAxesRT1000Hz, app.UIAxesRT1000HzCut, app.UIAxesRTBI1000Hz;
                app.StartSlider2000Hz, app.EndSlider2000Hz, app.UIAxesRT2000Hz, app.UIAxesRT2000HzCut, app.UIAxesRTBI2000Hz;
                app.StartSlider4000Hz, app.EndSlider4000Hz, app.UIAxesRT4000Hz, app.UIAxesRT4000HzCut, app.UIAxesRTBI4000Hz];
        
        app.lookupTableObjs = gobjects(6,1);
        app.lookupTableObjs = [ ...
                app.UITableRT;
                app.UITable250Hz;
                app.UITable500Hz;
                app.UITable1000Hz;
                app.UITable2000Hz;
                app.UITable4000Hz];
        
        app.lookupTableBIObjs = gobjects(6,1);
        app.lookupTableBIObjs = [ ...
                app.UITableBI250Hz;
                app.UITableBI500Hz;
                app.UITableBI1000Hz;
                app.UITableBI2000Hz;
                app.UITableBI4000Hz];
        
        app.xLines = gobjects([12,1]);
        app.xLinesBI = gobjects([10,1]);

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % This function sets the path of the selected file in the
            % EditField
            [rawDataFile, rawDataPath] = uigetfile('*.lvm', 'Locate file with raw data');
            if rawDataFile
                app.RawdatapathEditField.Value = fullfile(rawDataPath, rawDataFile);
            else
                rawDataErrorMsg = "The file has not been selected";
                rawDataErrorTitle = "Operation cancelled by user";
                DisplayUIAlert(app, rawDataErrorTitle, rawDataErrorMsg);  
            end
            drawnow; % app loses focus when uigetfile is used
            figure(app.UIFigure);
        end

        % Button pushed function: ProcessfileButton
        function ProcessfileButtonPushed(app, event)
            % Get dir structure for the file
            app.lvmFiles = dir(app.RawdatapathEditField.Value);
            
            % Variables initialisation
            fullFilePath = cell(numel(app.lvmFiles),1);
            fileName = cell(numel(app.lvmFiles),1);
            app.results = struct();
            
            for ii = 1 : numel(app.lvmFiles)
                % Creating path for every file
                fullFilePath{ii} = fullfile(app.lvmFiles(ii).folder, app.lvmFiles(ii).name);
                [~, fileName{ii}] = fileparts(fullFilePath{ii});
                app.fileData = lvm_import(fullFilePath{ii}, 0);
                
                % Reading signal information
                numChannels = app.fileData.Segment1.num_channels; 
                numSamples = app.fileData.Segment1.num_samples(1); % Sample rate for a single channel
                if ~numSamples
                    warning('Number of samples in the .lvm file is not written correctly, using 51250 samples, either correct it in the file or in the app file');
                    numSamples = 102450;
                end
                % Reading raw signal
                app.results(ii).pressureRaw = app.fileData.Segment1.data(:,1:numChannels); % in [Pa]
                app.results(ii).dt = 1/numSamples.*(1:numSamples)'; % vrijeme za svaki uzorak % krece od nule ili od dt?
                [app.results(ii).pressureRawMax, app.results(ii).pressureRawMaxInd] = max(app.results(ii).pressureRaw, [], 1);

                % Calculating SPRMS and SPL
                app.results(ii).soundPressureRMS = CalculateSPRMS(app, app.results(ii).pressureRaw);
                app.results(ii).SPL = CalculateSPL(app, app.results(ii).soundPressureRMS);
                app.results(ii).SPLNormalised = 20*log10(app.results(ii).SPL./max(app.results(ii).SPL, [], 1));
%                 SPLSection =  reshape(app.results(1).filteredSig(1).soundPressureRMS, 1250, 2, []); % 1250x2x41
%                 avgSPLSection = reshape(mean(SPLSection, 1), [], 2);
            end
            

            % Initialising variables
            app.data.filter.filtOrder = 3;
            a = zeros(size(app.data.filter.bandWidth, 1), 7); % Settings for butter give vector of length 7
            b = a;
            
            app.results(1).filteredSig = struct();
            for ii = 1 : size(app.data.filter.bandWidth, 1)
                app.results(1).filteredSig(ii).EDCBILogNorm = zeros(size(app.results(1).pressureRaw)); 
            end
            
            % Filtering the signal for each octave, calculating RMS, SPL,
            % SPL normalised, energy time curve normalised
            for ii = 1 : size(app.data.filter.bandWidth, 1)
                [b(ii,:), a(ii,:)] = butter(app.data.filter.filtOrder, [app.data.filter.lowerFreqs(ii), app.data.filter.upperFreqs(ii)]/(numSamples/2), 'bandpass');
                app.results(1).filteredSig(ii).pressureRaw = filter(b(ii,:), a(ii,:), app.results(1).pressureRaw);
                app.results(1).filteredSig(ii).soundPressureRMS = CalculateSPRMS(app, app.results(1).filteredSig(ii).pressureRaw);
                app.results(1).filteredSig(ii).SPL = CalculateSPL(app, app.results(1).filteredSig(ii).soundPressureRMS);
                app.results(1).filteredSig(ii).SPLLogNorm = 20*log10(app.results(1).filteredSig(ii).SPL./max(app.results(1).filteredSig(ii).SPL, [], 1));
                
%                 % Calculating the dynamic range for obtaining results
%                 Only signal in DB
%                 [app.results(1).filteredSig(ii).intStartInd, app.results(1).filteredSig(ii).intEndInd] = ...
%                     CalculateDynRangeT30(app, app.results(1).filteredSig(ii).pressureRaw);
                
                % Reference: https://dsp.stackexchange.com/questions/17121/calculation-of-reverberation-time-rt60-from-the-impulse-response
                
                % Energy time curve
                app.results(1).filteredSig(ii).analyticSig = hilbert(app.results(1).filteredSig(ii).pressureRaw);
                app.results(1).filteredSig(ii).envelopeSig = abs(app.results(1).filteredSig(ii).analyticSig);
                app.results(1).filteredSig(ii).energyTimeCurveLogNorm = 20*log10(app.results(1).filteredSig(ii).envelopeSig./max(app.results(1).filteredSig(ii).envelopeSig,[], 1));

                % Applying lowpass filter to envelope
                numSamplesForAvg = app.BrojuzorakazausrednjavanjesirovogtlakaEditField.Value;
                app.results(1).filteredSig(ii).energyTimeCurveFiltLog = 20*log10(movmean(app.results(1).filteredSig(ii).envelopeSig , [numSamplesForAvg 0], 1) ...
                    ./max(app.results(1).filteredSig(ii).envelopeSig,[], 1));
                    
                % Backward integration of Room Impulse Response
                % with the t from EditField component
                backgroundNoiseTimeEst = app.TimeforbackgroundnoisesEditField.Value;
                dt = app.results(1).dt;
                [~, minIndex] = min(abs(backgroundNoiseTimeEst-dt)); % finds index for the time in EditField component
                
                if backgroundNoiseTimeEst % integration with specific t
                    app.results(1).filteredSig(ii).EDCBILogNorm = flipud(10*log10(cumsum(app.results(1).filteredSig(ii).pressureRaw(minIndex:-1:1,:).^2) ...
                    ./sum(app.results(1).filteredSig(ii).pressureRaw(1:minIndex,:).^2)));
                else % integration throughout the whole signal
                    app.results(1).filteredSig(ii).EDCBILogNorm = flipud(10*log10(cumsum(app.results(1).filteredSig(ii).pressureRaw(end:-1:1,:).^2) ...
                    ./sum(app.results(1).filteredSig(ii).pressureRaw(1:end,:).^2)));
                end
                
                % Identification of filteredSig and corresponding central
                % frequency
                app.results(1).filteredSig(ii).centralFreq = app.data.filter.centralFreqs(ii);
            end
            
            % Plot regular and backward integrated decay curves
            PlotDecayCurves(app);
            PlotDecayCurvesBI(app);
            PlotLegends(app);
            
            dt = app.results(1).dt;
            SPL = app.results(1).SPL;
            
            %% Plot vertical lines for each graph
            % Plot RT
            app.xLines(1) = xline(app.UIAxesRT, dt(1000), 'LineWidth', 1, 'Label', 'Start');
            app.xLines(2) = xline(app.UIAxesRT, dt(end - 1000), 'LineWidth', 1, 'Label', 'End');
            % Plot RT 250 Hz
            app.xLines(3) = xline(app.UIAxesRT250Hz, dt(1000), 'LineWidth', 1, 'Label', 'Start');
            app.xLines(4) = xline(app.UIAxesRT250Hz, dt(end - 1000), 'LineWidth', 1, 'Label', 'End');
            % Plot RT 500 Hz
            app.xLines(5) = xline(app.UIAxesRT500Hz, dt(1000), 'LineWidth', 1, 'Label', 'Start');
            app.xLines(6) = xline(app.UIAxesRT500Hz, dt(end - 1000), 'LineWidth', 1, 'Label', 'End');
            % Plot RT 1000 Hz
            app.xLines(7) = xline(app.UIAxesRT1000Hz, dt(1000), 'LineWidth', 1, 'Label', 'Start');
            app.xLines(8) = xline(app.UIAxesRT1000Hz, dt(end - 1000), 'LineWidth', 1, 'Label', 'End');
            % Plot RT 2000 Hz
            app.xLines(9) = xline(app.UIAxesRT2000Hz, dt(1000), 'LineWidth', 1, 'Label', 'Start');
            app.xLines(10) = xline(app.UIAxesRT2000Hz, dt(end - 1000), 'LineWidth', 1, 'Label', 'End');
            % Plot RT 4000 Hz
            app.xLines(11) = xline(app.UIAxesRT4000Hz, dt(1000), 'LineWidth', 1, 'Label', 'Start');
            app.xLines(12) = xline(app.UIAxesRT4000Hz, dt(end - 1000), 'LineWidth', 1, 'Label', 'End');
            
            %% Plot slope lines 
            app.slopeLines = gobjects([6,1]);
            app.slopeLines(1) = line(app.UIAxesRT, [dt(1), dt(end)], [SPL(1), SPL(end)]);
            app.slopeLines(2) = line(app.UIAxesRT250Hz, [dt(1), dt(end)], [app.results(1).filteredSig(1).SPL(1), app.results(1).filteredSig(1).SPL(end)]);
            app.slopeLines(3) = line(app.UIAxesRT500Hz, [dt(1), dt(end)], [app.results(1).filteredSig(2).SPL(1), app.results(1).filteredSig(2).SPL(end)]);
            app.slopeLines(4) = line(app.UIAxesRT1000Hz, [dt(1), dt(end)], [app.results(1).filteredSig(3).SPL(1), app.results(1).filteredSig(3).SPL(end)]);
            app.slopeLines(5) = line(app.UIAxesRT2000Hz, [dt(1), dt(end)], [app.results(1).filteredSig(4).SPL(1), app.results(1).filteredSig(4).SPL(end)]);
            app.slopeLines(6) = line(app.UIAxesRT4000Hz, [dt(1), dt(end)], [app.results(1).filteredSig(5).SPL(1), app.results(1).filteredSig(5).SPL(end)]);
        
            %             app.slopeLines(2) = line(app.UIAxesBI, [dt(1), dt(end)], [SPL(1), SPL(end)]);

        end

        % Value changing function: EndSlider1000Hz, EndSlider2000Hz, 
        % ...and 10 other components
        function EndSliderValueChanging(app, event)
            % This callback is used in all slider components, so we pass
            % handle to the component which activated the event
            % to CalculateRT so we can find corresponding components for
            % that particular slider in lookupGraphObjs property
            
            % Find which component in the lookup table has fired the event  
            [row, col] = find(event.Source == app.lookupGraphObjs);
            
            % This function updates the vertical lines on the decay curve on the Reverberant time tab
            dt = app.results(1).dt;

            if isequal(col, 1) % 1st column are start sliders
                [~, minIndex] = min(abs(event.Value-dt)); % Finds index in dt closest to the slider value since its value can differ from dt
                app.xLines(row*2-1).Value = dt(minIndex); % Updates the vertical line value      
            else % 1st column are end sliders
                [~, minIndex] = min(abs(event.Value-dt));
                app.xLines(row*2).Value = dt(minIndex);
            end
            
            event.Source.Value = dt(minIndex);% Updates the slider value
            
            % Function to calculate reverberation time
            CalculateRT(app, event.Source)
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            % Function resets the whole UI (results, axes, tables, sliders)
            clear app.results
            cla(app.UIAxesDecayCurve);
            for ii = 1:size(app.lookupGraphObjs,1)
                cla(app.lookupGraphObjs(ii,3)); % Decay curves
                cla(app.lookupGraphObjs(ii,4)); % Decay curve extracts
            end
            
            for jj = 2:size(app.lookupGraphObjs,1)
                cla(app.lookupGraphObjs(jj,5)); % Energy time curve BI
            end
            
            % Clear table data
            set(app.lookupTableObjs, 'Data', []);
            set(app.lookupTableBIObjs, 'Data', []);
            set(app.UITableRTResults, 'Data', []);
            set(app.UITableBIRTResults, 'Data', []);
            set(app.UITableBIRTMeanResults, 'Data', []);
            
            % Sets default values for the sliders
            set(app.lookupGraphObjs(:,1), 'Value', 0);
            set(app.lookupGraphObjs(:,2), 'Value', 1);
            
        end

        % Value changed function: EndSlider1000Hz, EndSlider2000Hz, 
        % ...and 10 other components
        function EndSliderRTValueChanged(app, event)
            % Function to plot legends
            PlotLegends(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ResetMenu = uimenu(app.Menu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RawsignalTab = uitab(app.TabGroup);
            app.RawsignalTab.Title = 'Raw signal';
            app.GridLayout3 = uigridlayout(app.RawsignalTab);
            app.UIAxesDecayCurve = uiaxes(app.GridLayout3);
            title(app.UIAxesDecayCurve, 'Decay Curve')
            xlabel(app.UIAxesDecayCurve, 'Time[s]')
            ylabel(app.UIAxesDecayCurve, 'SPL[dB]')
            zlabel(app.UIAxesDecayCurve, 'Z')
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Title = 'Panel';
            app.RawdatapathEditFieldLabel = uilabel(app.Panel);
            app.RawdatapathEditFieldLabel.Text = 'Raw data path';
            app.RawdatapathEditField = uieditfield(app.Panel, 'text');
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ProcessfileButton = uibutton(app.Panel, 'push');
            app.ProcessfileButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessfileButtonPushed, true);
            app.ProcessfileButton.Text = 'Process file';
            app.BrojuzorakazausrednjavanjeRMSatlakaEditFieldLabel = uilabel(app.Panel);
            app.BrojuzorakazausrednjavanjeRMSatlakaEditFieldLabel.Text = {'Broj uzoraka za '; 'usrednjavanje RMS-a tlaka'};
            app.BrojuzorakazausrednjavanjeRMSatlakaEditField = uieditfield(app.Panel, 'numeric');
            app.BrojuzorakazausrednjavanjeRMSatlakaEditField.Value = 100;
            app.TimeforbackgroundnoisesEditFieldLabel = uilabel(app.Panel);
            app.TimeforbackgroundnoisesEditFieldLabel.Text = 'Time for background noise [s]';
            app.TimeforbackgroundnoisesEditField = uieditfield(app.Panel, 'numeric');
            app.BrojuzorakazausrednjavanjesirovogtlakaEditFieldLabel = uilabel(app.Panel);
            app.BrojuzorakazausrednjavanjesirovogtlakaEditFieldLabel.Text = {'Broj uzoraka za'; ' usrednjavanje sirovog tlaka'};
            app.BrojuzorakazausrednjavanjesirovogtlakaEditField = uieditfield(app.Panel, 'numeric');
            app.BrojuzorakazausrednjavanjesirovogtlakaEditField.Value = 1000;
            app.Panel2 = uipanel(app.GridLayout3);
            app.Panel2.Title = 'Panel2';
            app.Panel4 = uipanel(app.GridLayout3);
            app.Panel4.Title = 'Panel4';
            app.GridLayout18 = uigridlayout(app.Panel4);
            app.UITableRTResults = uitable(app.GridLayout18);
            app.UITableRTResults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableRTResults.RowName = {};
            app.UITableBIRTResults = uitable(app.GridLayout18);
            app.UITableBIRTResults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableBIRTResults.RowName = {};
            app.ReverberationtimessLabel = uilabel(app.GridLayout18);
            app.ReverberationtimessLabel.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessLabel.Text = 'Reverberation times [s]';
            app.Reverberationtimes30byBIsLabel = uilabel(app.GridLayout18);
            app.Reverberationtimes30byBIsLabel.Text = 'Reverberation times 30 by BI [s]';
            app.UITableBIRTMeanResults = uitable(app.GridLayout18);
            app.UITableBIRTMeanResults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableBIRTMeanResults.RowName = {};
            app.Meanreverberationtimes30byBIsLabel = uilabel(app.GridLayout18);
            app.Meanreverberationtimes30byBIsLabel.Text = 'Mean reverberation times 30 by BI [s]';
            app.ReverberanttimeTab = uitab(app.TabGroup);
            app.ReverberanttimeTab.Title = 'Reverberant time';
            app.GridLayout2 = uigridlayout(app.ReverberanttimeTab);
            app.UIAxesRTCut = uiaxes(app.GridLayout2);
            title(app.UIAxesRTCut, 'Decay curve extracted')
            xlabel(app.UIAxesRTCut, 'Time [s]')
            ylabel(app.UIAxesRTCut, 'SPL [dB]')
            zlabel(app.UIAxesRTCut, 'Z')
            app.UIAxesRT = uiaxes(app.GridLayout2);
            title(app.UIAxesRT, 'Decay curve')
            xlabel(app.UIAxesRT, 'Time[s]')
            ylabel(app.UIAxesRT, 'SPL [dB]')
            zlabel(app.UIAxesRT, 'Z')
            app.CursorsPanel = uipanel(app.GridLayout2);
            app.CursorsPanel.Title = 'Cursors';
            app.StartSliderLabel = uilabel(app.CursorsPanel);
            app.StartSliderLabel.Text = 'Start';
            app.StartSliderRT = uislider(app.CursorsPanel);
            app.StartSliderRT.Limits = [0 1];
            app.StartSliderRT.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.StartSliderRT.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.StartSliderRT.Tag = 'StartSliderRT';
            app.EndSliderLabel = uilabel(app.CursorsPanel);
            app.EndSliderLabel.Text = 'End';
            app.EndSliderRT = uislider(app.CursorsPanel);
            app.EndSliderRT.Limits = [0 1];
            app.EndSliderRT.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.EndSliderRT.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.EndSliderRT.Tag = 'EndSliderRT';
            app.EndSliderRT.Value = 1;
            app.ResultsPanel_6 = uipanel(app.GridLayout2);
            app.ResultsPanel_6.Title = 'Results';
            app.UITableRT = uitable(app.ResultsPanel_6);
            app.UITableRT.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableRT.RowName = {};
            app.OctavefiltersTab = uitab(app.TabGroup);
            app.OctavefiltersTab.Title = 'Octave filters';
            app.GridLayout8 = uigridlayout(app.OctavefiltersTab);
            app.TabGroup2 = uitabgroup(app.GridLayout8);
            app.HzTab_6 = uitab(app.TabGroup2);
            app.HzTab_6.Title = '250 Hz';
            app.GridLayout2_8 = uigridlayout(app.HzTab_6);
            app.UIAxesRTBI250Hz = uiaxes(app.GridLayout2_8);
            title(app.UIAxesRTBI250Hz, 'Normalised energy decay curve by BI')
            xlabel(app.UIAxesRTBI250Hz, 'Time [s]')
            ylabel(app.UIAxesRTBI250Hz, 'Amplitude [dB]')
            zlabel(app.UIAxesRTBI250Hz, 'Z')
            app.UIAxesRT250HzCut = uiaxes(app.GridLayout2_8);
            title(app.UIAxesRT250HzCut, 'Decay curve extracted')
            xlabel(app.UIAxesRT250HzCut, 'Time [s]')
            ylabel(app.UIAxesRT250HzCut, 'SPL [dB]')
            zlabel(app.UIAxesRT250HzCut, 'Z')
            app.UIAxesRT250Hz = uiaxes(app.GridLayout2_8);
            title(app.UIAxesRT250Hz, 'Decay curve (bandpass) ')
            xlabel(app.UIAxesRT250Hz, 'Time[s]')
            ylabel(app.UIAxesRT250Hz, 'SPL [dB]')
            zlabel(app.UIAxesRT250Hz, 'Z')
            app.CursorsPanel_8 = uipanel(app.GridLayout2_8);
            app.CursorsPanel_8.Title = 'Cursors';
            app.StartSlider_2Label = uilabel(app.CursorsPanel_8);
            app.StartSlider_2Label.Text = 'Start';
            app.StartSlider250Hz = uislider(app.CursorsPanel_8);
            app.StartSlider250Hz.Limits = [0 1];
            app.StartSlider250Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.StartSlider250Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.StartSlider250Hz.Tag = 'StartSlider';
            app.EndSlider_2Label = uilabel(app.CursorsPanel_8);
            app.EndSlider_2Label.Text = 'End';
            app.EndSlider250Hz = uislider(app.CursorsPanel_8);
            app.EndSlider250Hz.Limits = [0 1];
            app.EndSlider250Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.EndSlider250Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.EndSlider250Hz.Tag = 'EndSlider';
            app.EndSlider250Hz.Value = 1;
            app.ResultsPanel = uipanel(app.GridLayout2_8);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout17 = uigridlayout(app.ResultsPanel);
            app.UITableBI250Hz = uitable(app.GridLayout17);
            app.UITableBI250Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableBI250Hz.RowName = {};
            app.UITable250Hz = uitable(app.GridLayout17);
            app.UITable250Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable250Hz.RowName = {};
            app.ReverberationtimessslidersLabel = uilabel(app.GridLayout17);
            app.ReverberationtimessslidersLabel.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessslidersLabel.Text = 'Reverberation times [s] sliders';
            app.ReverberationtimesBIsLabel = uilabel(app.GridLayout17);
            app.ReverberationtimesBIsLabel.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimesBIsLabel.Text = 'Reverberation times BI [s]';
            app.HzTab_7 = uitab(app.TabGroup2);
            app.HzTab_7.Title = '500 Hz';
            app.GridLayout2_9 = uigridlayout(app.HzTab_7);
            app.UIAxesRTBI500Hz = uiaxes(app.GridLayout2_9);
            title(app.UIAxesRTBI500Hz, 'Normalised energy decay curve by BI')
            xlabel(app.UIAxesRTBI500Hz, 'Time [s]')
            ylabel(app.UIAxesRTBI500Hz, 'Amplitude [dB]')
            zlabel(app.UIAxesRTBI500Hz, 'Z')
            app.UIAxesRT500HzCut = uiaxes(app.GridLayout2_9);
            title(app.UIAxesRT500HzCut, 'Decay curve extracted')
            xlabel(app.UIAxesRT500HzCut, 'Time [s]')
            ylabel(app.UIAxesRT500HzCut, 'SPL [dB]')
            zlabel(app.UIAxesRT500HzCut, 'Z')
            app.UIAxesRT500Hz = uiaxes(app.GridLayout2_9);
            title(app.UIAxesRT500Hz, 'Decay curve (bandpass)')
            xlabel(app.UIAxesRT500Hz, 'Time[s]')
            ylabel(app.UIAxesRT500Hz, 'SPL [dB]')
            zlabel(app.UIAxesRT500Hz, 'Z')
            app.CursorsPanel_9 = uipanel(app.GridLayout2_9);
            app.CursorsPanel_9.Title = 'Cursors';
            app.StartSlider_3Label = uilabel(app.CursorsPanel_9);
            app.StartSlider_3Label.Text = 'Start';
            app.StartSlider500Hz = uislider(app.CursorsPanel_9);
            app.StartSlider500Hz.Limits = [0 1];
            app.StartSlider500Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.StartSlider500Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.StartSlider500Hz.Tag = 'StartSlider';
            app.EndSlider_3Label = uilabel(app.CursorsPanel_9);
            app.EndSlider_3Label.Text = 'End';
            app.EndSlider500Hz = uislider(app.CursorsPanel_9);
            app.EndSlider500Hz.Limits = [0 1];
            app.EndSlider500Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.EndSlider500Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.EndSlider500Hz.Tag = 'EndSlider';
            app.EndSlider500Hz.Value = 1;
            app.ResultsPanel_2 = uipanel(app.GridLayout2_9);
            app.ResultsPanel_2.Title = 'Results';
            app.GridLayout16 = uigridlayout(app.ResultsPanel_2);
            app.UITable500Hz = uitable(app.GridLayout16);
            app.UITable500Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable500Hz.RowName = {};
            app.UITableBI500Hz = uitable(app.GridLayout16);
            app.UITableBI500Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableBI500Hz.RowName = {};
            app.ReverberationtimessslidersLabel_2 = uilabel(app.GridLayout16);
            app.ReverberationtimessslidersLabel_2.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessslidersLabel_2.Text = 'Reverberation times [s] sliders';
            app.ReverberationtimessBILabel = uilabel(app.GridLayout16);
            app.ReverberationtimessBILabel.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessBILabel.Text = 'Reverberation times [s] BI';
            app.HzTab_8 = uitab(app.TabGroup2);
            app.HzTab_8.Title = '1000 Hz';
            app.GridLayout2_10 = uigridlayout(app.HzTab_8);
            app.UIAxesRTBI1000Hz = uiaxes(app.GridLayout2_10);
            title(app.UIAxesRTBI1000Hz, 'Normalised energy decay curve by BI')
            xlabel(app.UIAxesRTBI1000Hz, 'Time [s]')
            ylabel(app.UIAxesRTBI1000Hz, 'Amplitude [dB]')
            zlabel(app.UIAxesRTBI1000Hz, 'Z')
            app.UIAxesRT1000HzCut = uiaxes(app.GridLayout2_10);
            title(app.UIAxesRT1000HzCut, 'Decay curve extracted')
            xlabel(app.UIAxesRT1000HzCut, 'Time [s]')
            ylabel(app.UIAxesRT1000HzCut, 'SPL [dB]')
            zlabel(app.UIAxesRT1000HzCut, 'Z')
            app.UIAxesRT1000Hz = uiaxes(app.GridLayout2_10);
            title(app.UIAxesRT1000Hz, 'Decay curve (bandpass)')
            xlabel(app.UIAxesRT1000Hz, 'Time[s]')
            ylabel(app.UIAxesRT1000Hz, 'SPL [dB]')
            zlabel(app.UIAxesRT1000Hz, 'Z')
            app.CursorsPanel_10 = uipanel(app.GridLayout2_10);
            app.CursorsPanel_10.Title = 'Cursors';
            app.StartSlider_4Label = uilabel(app.CursorsPanel_10);
            app.StartSlider_4Label.Text = 'Start';
            app.StartSlider1000Hz = uislider(app.CursorsPanel_10);
            app.StartSlider1000Hz.Limits = [0 1];
            app.StartSlider1000Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.StartSlider1000Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.StartSlider1000Hz.Tag = 'StartSlider';
            app.EndSlider_4Label = uilabel(app.CursorsPanel_10);
            app.EndSlider_4Label.Text = 'End';
            app.EndSlider1000Hz = uislider(app.CursorsPanel_10);
            app.EndSlider1000Hz.Limits = [0 1];
            app.EndSlider1000Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.EndSlider1000Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.EndSlider1000Hz.Tag = 'EndSlider';
            app.EndSlider1000Hz.Value = 1;
            app.ResultsPanel_3 = uipanel(app.GridLayout2_10);
            app.ResultsPanel_3.Title = 'Results';
            app.GridLayout15 = uigridlayout(app.ResultsPanel_3);
            app.UITable1000Hz = uitable(app.GridLayout15);
            app.UITable1000Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable1000Hz.RowName = {};
            app.UITableBI1000Hz = uitable(app.GridLayout15);
            app.UITableBI1000Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableBI1000Hz.RowName = {};
            app.ReverberationtimessslidersLabel_3 = uilabel(app.GridLayout15);
            app.ReverberationtimessslidersLabel_3.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessslidersLabel_3.Text = 'Reverberation times [s] sliders';
            app.ReverberationtimessBILabel_2 = uilabel(app.GridLayout15);
            app.ReverberationtimessBILabel_2.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessBILabel_2.Text = 'Reverberation times [s] BI';
            app.HzTab_9 = uitab(app.TabGroup2);
            app.HzTab_9.Title = '2000 Hz';
            app.GridLayout2_11 = uigridlayout(app.HzTab_9);
            app.UIAxesRTBI2000Hz = uiaxes(app.GridLayout2_11);
            title(app.UIAxesRTBI2000Hz, 'Normalised energy decay curve by BI')
            xlabel(app.UIAxesRTBI2000Hz, 'Time [s]')
            ylabel(app.UIAxesRTBI2000Hz, 'Amplitude [dB]')
            zlabel(app.UIAxesRTBI2000Hz, 'Z')
            app.UIAxesRT2000HzCut = uiaxes(app.GridLayout2_11);
            title(app.UIAxesRT2000HzCut, 'Decay curve extracted')
            xlabel(app.UIAxesRT2000HzCut, 'Time [s]')
            ylabel(app.UIAxesRT2000HzCut, 'SPL [dB]')
            zlabel(app.UIAxesRT2000HzCut, 'Z')
            app.UIAxesRT2000Hz = uiaxes(app.GridLayout2_11);
            title(app.UIAxesRT2000Hz, 'Decay curve (bandpass)')
            xlabel(app.UIAxesRT2000Hz, 'Time[s]')
            ylabel(app.UIAxesRT2000Hz, 'SPL [dB]')
            zlabel(app.UIAxesRT2000Hz, 'Z')
            app.CursorsPanel_11 = uipanel(app.GridLayout2_11);
            app.CursorsPanel_11.Title = 'Cursors';
            app.StartSlider_6Label = uilabel(app.CursorsPanel_11);
            app.StartSlider_6Label.Text = 'Start';
            app.StartSlider2000Hz = uislider(app.CursorsPanel_11);
            app.StartSlider2000Hz.Limits = [0 1];
            app.StartSlider2000Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.StartSlider2000Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.StartSlider2000Hz.Tag = 'StartSlider';
            app.EndSlider_6Label = uilabel(app.CursorsPanel_11);
            app.EndSlider_6Label.Text = 'End';
            app.EndSlider2000Hz = uislider(app.CursorsPanel_11);
            app.EndSlider2000Hz.Limits = [0 1];
            app.EndSlider2000Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.EndSlider2000Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.EndSlider2000Hz.Tag = 'EndSlider';
            app.EndSlider2000Hz.Value = 1;
            app.ResultsPanel_4 = uipanel(app.GridLayout2_11);
            app.ResultsPanel_4.Title = 'Results';
            app.GridLayout14 = uigridlayout(app.ResultsPanel_4);
            app.UITable2000Hz = uitable(app.GridLayout14);
            app.UITable2000Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2000Hz.RowName = {};
            app.UITableBI2000Hz = uitable(app.GridLayout14);
            app.UITableBI2000Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableBI2000Hz.RowName = {};
            app.ReverberationtimessslidersLabel_4 = uilabel(app.GridLayout14);
            app.ReverberationtimessslidersLabel_4.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessslidersLabel_4.Text = 'Reverberation times [s] sliders';
            app.ReverberationtimessBILabel_3 = uilabel(app.GridLayout14);
            app.ReverberationtimessBILabel_3.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessBILabel_3.Text = 'Reverberation times [s] BI';
            app.HzTab_10 = uitab(app.TabGroup2);
            app.HzTab_10.Title = '4000 Hz';
            app.GridLayout2_12 = uigridlayout(app.HzTab_10);
            app.UIAxesRTBI4000Hz = uiaxes(app.GridLayout2_12);
            title(app.UIAxesRTBI4000Hz, 'Normalised energy decay curve by BI')
            xlabel(app.UIAxesRTBI4000Hz, 'Time [s]')
            ylabel(app.UIAxesRTBI4000Hz, 'Amplitude [dB]')
            zlabel(app.UIAxesRTBI4000Hz, 'Z')
            app.UIAxesRT4000Hz = uiaxes(app.GridLayout2_12);
            title(app.UIAxesRT4000Hz, 'Decay curve (bandpass)')
            xlabel(app.UIAxesRT4000Hz, 'Time[s]')
            ylabel(app.UIAxesRT4000Hz, 'SPL [dB]')
            zlabel(app.UIAxesRT4000Hz, 'Z')
            app.UIAxesRT4000HzCut = uiaxes(app.GridLayout2_12);
            title(app.UIAxesRT4000HzCut, 'Decay curve extracted')
            xlabel(app.UIAxesRT4000HzCut, 'Time [s]')
            ylabel(app.UIAxesRT4000HzCut, 'SPL [dB]')
            zlabel(app.UIAxesRT4000HzCut, 'Z')
            app.CursorsPanel_12 = uipanel(app.GridLayout2_12);
            app.CursorsPanel_12.Title = 'Cursors';
            app.StartSlider_5Label = uilabel(app.CursorsPanel_12);
            app.StartSlider_5Label.Text = 'Start';
            app.StartSlider4000Hz = uislider(app.CursorsPanel_12);
            app.StartSlider4000Hz.Limits = [0 1];
            app.StartSlider4000Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.StartSlider4000Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.StartSlider4000Hz.Tag = 'StartSlider';
            app.EndSlider_5Label = uilabel(app.CursorsPanel_12);
            app.EndSlider_5Label.Text = 'End';
            app.EndSlider4000Hz = uislider(app.CursorsPanel_12);
            app.EndSlider4000Hz.Limits = [0 1];
            app.EndSlider4000Hz.ValueChangedFcn = createCallbackFcn(app, @EndSliderRTValueChanged, true);
            app.EndSlider4000Hz.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.EndSlider4000Hz.Tag = 'EndSlider';
            app.EndSlider4000Hz.Value = 1;
            app.ResultsPanel_5 = uipanel(app.GridLayout2_12);
            app.ResultsPanel_5.Title = 'Results';
            app.GridLayout13 = uigridlayout(app.ResultsPanel_5);
            app.UITable4000Hz = uitable(app.GridLayout13);
            app.UITable4000Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4000Hz.RowName = {};
            app.UITableBI4000Hz = uitable(app.GridLayout13);
            app.UITableBI4000Hz.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableBI4000Hz.RowName = {};
            app.ReverberationtimessslidersLabel_5 = uilabel(app.GridLayout13);
            app.ReverberationtimessslidersLabel_5.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessslidersLabel_5.Text = 'Reverberation times [s] sliders';
            app.ReverberationtimessBILabel_4 = uilabel(app.GridLayout13);
            app.ReverberationtimessBILabel_4.Tooltip = {'Data will be filled when sliders for all octave filtered responses have been altered'};
            app.ReverberationtimessBILabel_4.Text = 'Reverberation times [s] BI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReverberantTime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end